classdef gui_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ReadBoardButton            matlab.ui.control.Button
        DecodeQRButton             matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        InitialConfigurationLabel  matlab.ui.control.Label
        UITable_2                  matlab.ui.control.Table
        Label                      matlab.ui.control.Label
        FinalConfigurationLabel    matlab.ui.control.Label
        SolveTimeEditFieldLabel    matlab.ui.control.Label
        SolveTimeEditField         matlab.ui.control.NumericEditField
        Button                     matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function message = func(app)

            end
        
    end


    methods (Access = private)

        % Button pushed function: DecodeQRButton
        function DecodeQRButtonPushed(app, event)
            import com.google.zxing.qrcode.QRCodeReader;
            import com.google.zxing.client.j2se.BufferedImageLuminanceSource;
            import com.google.zxing.BinaryBitmap;
            import com.google.zxing.common.HybridBinarizer;
            import com.google.zxing.Result;
            
            % dbstop;
            
            im = imread('qrcode.jpg');
            imshow(im,'Parent',app.UIAxes);
            
            jimg = im2java2d(im);
            source = BufferedImageLuminanceSource(jimg);
            bitmap = BinaryBitmap(HybridBinarizer(source));
            qr_reader = QRCodeReader;
            try 
                result = qr_reader.decode(bitmap);
                %parsedResult = ResultParser.parseResult(result);
                message = char(result.getText());
            catch e
                message = [];        
            end
            
%             app.SolveTimeEditField.Text = message;
            f = msgbox(message);
            
            clear source;
            clear jimg;
            clear bitmap;
            % Created with NEWFCN.m by Frank Gonz�lez-Morphy  
            % Contact...: frank.gonzalez-morphy@mathworks.de  
            % ===== EOF ====== [decode_qr.m] ======  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ReadBoardButton = uibutton(app.UIFigure, 'push');
            app.ReadBoardButton.Icon = 'apoorvakapadia.jpg';
            app.ReadBoardButton.Text = 'Read Board';
            app.DecodeQRButton = uibutton(app.UIFigure, 'push');
            app.DecodeQRButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeQRButtonPushed, true);
            app.DecodeQRButton.Icon = 'apoorvakapadia.jpg';
            app.DecodeQRButton.Text = 'Decode QR';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Icon = 'apoorvakapadia.jpg';
            app.StartButton.Text = 'Start';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Location'; 'Color'};
            app.UITable.RowName = {};
            app.InitialConfigurationLabel = uilabel(app.UIFigure);
            app.InitialConfigurationLabel.Text = 'Initial Configuration';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Location'; 'Color'};
            app.UITable_2.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FinalConfigurationLabel = uilabel(app.UIFigure);
            app.FinalConfigurationLabel.Text = 'Final Configuration';
            app.SolveTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SolveTimeEditFieldLabel.Text = 'Solve Time';
            app.SolveTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'apoorvakapadia.jpg';
            app.Button.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end